classdef Graficke_rozlozeni_prvocisel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        VisualizeButton            matlab.ui.control.Button
        UpperlimitEditFieldLabel   matlab.ui.control.Label
        UpperlimitEditField        matlab.ui.control.EditField
        FindprimenumbersButton     matlab.ui.control.Button
        ListofprimesTextAreaLabel  matlab.ui.control.Label
        ListofprimesTextArea       matlab.ui.control.TextArea
        ClearallcontentsButton     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        a
        fin_dat
        prime_num
        prime_num1
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
         
            %distance calc
              breakpoint_vector1 = app.prime_num;
              breakpoint_vector_dist = [breakpoint_vector1] - [1 breakpoint_vector1(1:end-1)];
              position = [0,0];
              point = [1:app.a];
              
            %4 angles
              pos_1 = breakpoint_vector_dist(1:4:end);
              pos_2 = breakpoint_vector_dist(2:4:end);
              pos_3 = breakpoint_vector_dist(3:4:end);
              pos_4 = breakpoint_vector_dist(4:4:end);
            
            %dif length => add zero
              if not(length(pos_1) == length(pos_2)) % == length(pos_3) == length(pos_4)
                  pos_2 = [pos_2 0];
                  pos_3 = [pos_3 0];
                  pos_4 = [pos_4 0];
                  
              elseif not (length(pos_2) == length(pos_3))
                       pos_3 = [pos_3 0];
                       pos_4 = [pos_4 0];
                       
              elseif not (length(pos_3) == length(pos_4)) 
                          pos_4 = [pos_4 0];
              end
                
              %coordinates loop
              pp_4 = 0;
              posfin  = [0 0];
              for i = 1:length(pos_1)
                  
                  pp_1 = pp_4 + [pos_1(i) 0];
                  pp_2 = pp_1 + [0 pos_2(i)];
                  pp_3 = pp_2 + [-pos_3(i) 0];
                  pp_4 = pp_3 + [0 -pos_4(i)];
                  
                  posfin = [posfin; pp_1; pp_2; pp_3; pp_4];
              end
  
             app.fin_dat = posfin;
             %app.fin_dat = str2double(regexp(app.fin_dat,'\d*','match'));
             assignin('base','fin_dat',app.fin_dat);
             
              x = app.fin_dat(:,1);
              y = app.fin_dat(:,2);
              
            
             hold(app.UIAxes);
             
             plot(app.UIAxes,x,y,'k.-');
             plot (app.UIAxes,0,0,'r*');
             axis(app.UIAxes, "equal") ;
        end

        % Callback function
        function UpperlimitEditFieldValueChanged(app, event)
            %value = app.UpperlimitEditField.Value;
            
        end

        % Callback function
        function ShowstartingpointCheckBoxValueChanged(app, event)
            %value = app.ShowstartingpointCheckBox.Value;
            
        end

        % Button pushed function: FindprimenumbersButton
        function FindprimenumbersButtonPushed(app, event)
            dat = app.UpperlimitEditField.Value;
             app.a = str2double(regexp(dat,'\d*','match'));
             assignin('base','a',app.a);
             
               %sieve of Eratosthenes
                n = 1:app.a;
                inc = 2;
              
              while inc^2 <= app.a
                n(inc^2:inc:app.a) = 0;
                inc = find(n>inc,1);
              end
              
              %lost of primes
              app.prime_num = n(n>1);
              app.prime_num1=num2str(app.prime_num);
              assignin('base','prime_num1',app.prime_num1);
              app.ListofprimesTextArea.Value = app.prime_num1;
              
              if isempty(app.UpperlimitEditField.Value)
                    errordlg('Upper limit not found!.','File Error')
              end
              
        end

        % Value changed function: UpperlimitEditField
        function UpperlimitEditFieldValueChanged2(app, event)
            value = app.UpperlimitEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ListofprimesTextArea
        function ListofprimesTextAreaValueChanged(app, event)
            value = app.ListofprimesTextArea.Value;
            
        end

        % Button pushed function: ClearallcontentsButton
        function ClearallcontentsButtonPushed(app, event)
            app.UpperlimitEditField.Value = '';
            app.ListofprimesTextArea.Value = '';
            cla(app.UIAxes, "reset");
            
            clc
            clear all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.UpperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.UpperlimitEditField = uieditfield(app.UIFigure, 'text');
            app.UpperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperlimitEditFieldValueChanged2, true);
            app.FindprimenumbersButton = uibutton(app.UIFigure, 'push');
            app.FindprimenumbersButton.ButtonPushedFcn = createCallbackFcn(app, @FindprimenumbersButtonPushed, true);
            app.FindprimenumbersButton.Text = 'Find prime numbers';
            app.ListofprimesTextAreaLabel = uilabel(app.UIFigure);
            app.ListofprimesTextAreaLabel.Text = 'List of primes';
            app.ListofprimesTextArea = uitextarea(app.UIFigure);
            app.ListofprimesTextArea.ValueChangedFcn = createCallbackFcn(app, @ListofprimesTextAreaValueChanged, true);
            app.ClearallcontentsButton = uibutton(app.UIFigure, 'push');
            app.ClearallcontentsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallcontentsButtonPushed, true);
            app.ClearallcontentsButton.Text = 'Clear all contents';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualisation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graficke_rozlozeni_prvocisel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end