classdef crc_code_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ENCODERTab                    matlab.ui.container.Tab
        VstupnedatavoformateabcxEditFieldLabel_2  matlab.ui.control.Label
        VstupnedatavoformateabcxEditField_2  matlab.ui.control.EditField
        VstupnedatavofotmateabcxEditFieldLabel_3  matlab.ui.control.Label
        VstupnedatavofotmateabcxEditField_3  matlab.ui.control.EditField
        EncodeButton                  matlab.ui.control.Button
        ClearallcontentsButton        matlab.ui.control.Button
        VyslednykodTextAreaLabel_2    matlab.ui.control.Label
        VyslednykodTextArea_2         matlab.ui.control.TextArea
        CRCENCODERLabel               matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        SaveButton_2                  matlab.ui.control.Button
        DECODERTab                    matlab.ui.container.Tab
        DecodeButton                  matlab.ui.control.Button
        ClearallcontentsButton_2      matlab.ui.control.Button
        VyslednykodTextAreaLabel_3    matlab.ui.control.Label
        VyslednykodTextArea_3         matlab.ui.control.TextArea
        CRCDECODERLabel               matlab.ui.control.Label
        SaveButton_3                  matlab.ui.control.Button
        SaveButton_4                  matlab.ui.control.Button
        ReceiveddataformatabcxEditFieldLabel  matlab.ui.control.Label
        ReceiveddataformatabcxEditField  matlab.ui.control.EditField
        GeneratorformatabcxEditFieldLabel  matlab.ui.control.Label
        GeneratorformatabcxEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        data
        generator
        result
        result1
        check
        message
        message1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % VstupnedatavoformateabcxEditField_2
        function VstupnedatavoformateabcxEditField_2ValueChanged(app, event)
            %data = app.VstupnedatavoformateabcxEditField_2.Value;
            
        end

        % Value changed function: 
        % VstupnedatavofotmateabcxEditField_3
        function VstupnedatavofotmateabcxEditField_3ValueChanged(app, event)
            %generator = app.VstupnedatavofotmateabcxEditField_3.Value;
            
        end

        % Button pushed function: ClearallcontentsButton
        function ClearallcontentsButtonPushed(app, event)
            
          
            app.VstupnedatavoformateabcxEditField_2.Value = '';
            app.VstupnedatavofotmateabcxEditField_3.Value = '';
            app.VyslednykodTextArea_2.Value = '';
            
            clc
            clear all
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            lngtx = length(app.data); %dlzka dat. polynomu
            lngty = length(app.generator); %dlzka generatoru

            resultlngt = lngty+lngtx-1; %vysledna dlzka polynomu
            cdata = app.data;

            %pridanie nul na koniec 
            for a=1:(lngty-1)
            cdata(lngtx+a) = 0;
            end

            %kontrola
            disp("data plus nulovy doplnek");
            disp(cdata)

            for a=1:lngty
               comp(a)= cdata(a);
            end

            %vymedzenie prveho vypoctu v dlzke generatora (mod2)
            tr = xor(comp,app.generator);

            for a=1:lngty
                cdata(a)=tr(a);
            end

            %disp(cdata)


            for len=1:length(app.data)
                %while 1
                 if cdata(len)==1
                     ndata=cdata(len:(len+lngty-1));
                     trn = xor(ndata(1:lngty),app.generator);
                     cdata(len:len+lngty-1)=trn;
                 end
     
                 while 1
                 len=len+1; 
                if len>lngtx-1
                   break
                end
     
            % end
             end
             end

            app.result = cdata(lngtx+1:end)
            app.message = [app.data app.result]

            assignin('base','result',app.result);
            assignin('base','message',app.message);
            %disp("vysledny polynom")
            %disp(app.result);
            
            app.message1=num2str(app.message);
            
            app.VyslednykodTextArea_2.Value = app.message1

        end

        % Value changed function: VyslednykodTextArea_2
        function VyslednykodTextArea_2ValueChanged(app, event)
            %value = app.VyslednykodTextArea_2.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             dat = app.VstupnedatavoformateabcxEditField_2.Value;
             app.data = str2double(regexp(dat,'\d*','match'))
             assignin('base','data',app.data);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
             gen = app.VstupnedatavofotmateabcxEditField_3.Value;
             app.generator = str2double(regexp(gen,'\d*','match'))
             assignin('base','generator',app.generator);
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
             dat = app.ReceiveddataformatabcxEditField.Value;
             app.data = str2double(regexp(dat,'\d*','match'))
             assignin('base','data',app.data);
        end

        % Value changed function: ReceiveddataformatabcxEditField
        function ReceiveddataformatabcxEditFieldValueChanged(app, event)
            %value = app.ReceiveddataformatabcxEditField.Value;
            
        end

        % Value changed function: GeneratorformatabcxEditField
        function GeneratorformatabcxEditFieldValueChanged(app, event)
            %value = app.GeneratorformatabcxEditField.Value;
            
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            gen = app.GeneratorformatabcxEditField.Value;
            app.generator = str2double(regexp(gen,'\d*','match'))
            assignin('base','generator',app.generator);
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            lngtx = length(app.data); %dlzka dat. polynomu
            lngty = length(app.generator); %dlzka generatoru
            
            resultlngt = lngtx; %vysledna dlzka polynomu
            cdata = app.data;
            
           
            %disp(cdata)
            
            for a=1:lngty
                comp(a)= cdata(a);
            end
            
            %vymedzenie prveho vypoctu v dlzke generatora (mod2)
            tr = xor(comp,app.generator);
            
            for a=1:lngty
                cdata(a)=tr(a);
            end
            
            %disp(cdata)


             for len=1:(lngtx-lngty+1)
                %while 1
                 if cdata(len)==1
                     ndata=cdata(len:(len+lngty-1));
                     trn = xor(ndata(1:lngty),app.generator);
                     cdata(len:len+lngty-1)=trn;
                 end
                 
                 while 1
                 len=len+1; 
                if len>lngtx-1
                    break
                end
                 
            % end
             end
            end

            app.result = cdata(lngtx-lngty+2:end)
            app.message = [app.data app.result]
            
             if app.result == zeros(1,lngty-1)
                 txt = 'Error not found.'
                    else  txt = 'An error was found. Please resend the message.'
             end

            assignin('base','result',app.result);
            assignin('base','message',app.message);
            %disp("vysledny polynom")
            %disp(app.result);
            
            app.message1=num2str(app.result);
            assignin('base','message1',app.message1);
             
            app.VyslednykodTextArea_3.Value = app.message1
            app.VyslednykodTextArea_3.Value = [app.VyslednykodTextArea_3.Value;txt]
        end

        % Value changed function: VyslednykodTextArea_3
        function VyslednykodTextArea_3ValueChanged(app, event)
            %value = app.VyslednykodTextArea_3.Value;
            
        end

        % Button pushed function: ClearallcontentsButton_2
        function ClearallcontentsButton_2Pushed(app, event)
            app.ReceiveddataformatabcxEditField.Value = '';
            app.GeneratorformatabcxEditField.Value = '';
            app.VyslednykodTextArea_3.Value = '';
            
            clc
            clear all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ENCODERTab = uitab(app.TabGroup);
            app.ENCODERTab.Title = 'ENCODER';
            app.VstupnedatavoformateabcxEditFieldLabel_2 = uilabel(app.ENCODERTab);
            app.VstupnedatavoformateabcxEditFieldLabel_2.Text = 'Message bits (format [a b c ... x])';
            app.VstupnedatavoformateabcxEditField_2 = uieditfield(app.ENCODERTab, 'text');
            app.VstupnedatavoformateabcxEditField_2.ValueChangedFcn = createCallbackFcn(app, @VstupnedatavoformateabcxEditField_2ValueChanged, true);
            app.VstupnedatavoformateabcxEditField_2.Value = '[1 0 1 1 0 1 1 1 0]';
            app.VstupnedatavofotmateabcxEditFieldLabel_3 = uilabel(app.ENCODERTab);
            app.VstupnedatavofotmateabcxEditFieldLabel_3.Text = 'Generator (format [a b c ... x])';
            app.VstupnedatavofotmateabcxEditField_3 = uieditfield(app.ENCODERTab, 'text');
            app.VstupnedatavofotmateabcxEditField_3.ValueChangedFcn = createCallbackFcn(app, @VstupnedatavofotmateabcxEditField_3ValueChanged, true);
            app.VstupnedatavofotmateabcxEditField_3.Value = '[1 1 0 1]';
            app.EncodeButton = uibutton(app.ENCODERTab, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.ClearallcontentsButton = uibutton(app.ENCODERTab, 'push');
            app.ClearallcontentsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallcontentsButtonPushed, true);
            app.ClearallcontentsButton.Text = 'Clear all contents';
            app.VyslednykodTextAreaLabel_2 = uilabel(app.ENCODERTab);
            app.VyslednykodTextAreaLabel_2.Text = 'Encoded message';
            app.VyslednykodTextArea_2 = uitextarea(app.ENCODERTab);
            app.VyslednykodTextArea_2.ValueChangedFcn = createCallbackFcn(app, @VyslednykodTextArea_2ValueChanged, true);
            app.CRCENCODERLabel = uilabel(app.ENCODERTab);
            app.CRCENCODERLabel.Text = 'CRC ENCODER';
            app.SaveButton = uibutton(app.ENCODERTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveButton_2 = uibutton(app.ENCODERTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.DECODERTab = uitab(app.TabGroup);
            app.DECODERTab.Title = 'DECODER';
            app.DecodeButton = uibutton(app.DECODERTab, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.ClearallcontentsButton_2 = uibutton(app.DECODERTab, 'push');
            app.ClearallcontentsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearallcontentsButton_2Pushed, true);
            app.ClearallcontentsButton_2.Text = 'Clear all contents';
            app.VyslednykodTextAreaLabel_3 = uilabel(app.DECODERTab);
            app.VyslednykodTextAreaLabel_3.Text = {'Check bits '; '+ evaluation'};
            app.VyslednykodTextArea_3 = uitextarea(app.DECODERTab);
            app.VyslednykodTextArea_3.ValueChangedFcn = createCallbackFcn(app, @VyslednykodTextArea_3ValueChanged, true);
            app.CRCDECODERLabel = uilabel(app.DECODERTab);
            app.CRCDECODERLabel.Text = 'CRC DECODER';
            app.SaveButton_3 = uibutton(app.DECODERTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.SaveButton_4 = uibutton(app.DECODERTab, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Text = 'Save';
            app.ReceiveddataformatabcxEditFieldLabel = uilabel(app.DECODERTab);
            app.ReceiveddataformatabcxEditFieldLabel.Text = 'Received data (format [a b c ... x])';
            app.ReceiveddataformatabcxEditField = uieditfield(app.DECODERTab, 'text');
            app.ReceiveddataformatabcxEditField.ValueChangedFcn = createCallbackFcn(app, @ReceiveddataformatabcxEditFieldValueChanged, true);
            app.ReceiveddataformatabcxEditField.Value = '[1 0 1 1 0 1 1 1 0 0 1 1]';
            app.GeneratorformatabcxEditFieldLabel = uilabel(app.DECODERTab);
            app.GeneratorformatabcxEditFieldLabel.Text = 'Generator (format [a b c ... x])';
            app.GeneratorformatabcxEditField = uieditfield(app.DECODERTab, 'text');
            app.GeneratorformatabcxEditField.ValueChangedFcn = createCallbackFcn(app, @GeneratorformatabcxEditFieldValueChanged, true);
            app.GeneratorformatabcxEditField.Value = '[1 1 0 1]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crc_code_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end